classdef LD3_DigitalTwin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LD3COOLINGWATERSYSTEMDIGITALTWINBasicOxygenFurnaceLD3CoolingWaterSystemLabel  matlab.ui.control.Label
        GridLayout                    matlab.ui.container.GridLayout
        cspi                          matlab.ui.control.Label
        reynoldsnumber                matlab.ui.control.Label
        velocity                      matlab.ui.control.Label
        massflow                      matlab.ui.control.Label
        heatremoved                   matlab.ui.control.Label
        pressuredrop                  matlab.ui.control.Label
        pumphead                      matlab.ui.control.Label
        EngineeringResultsPanel       matlab.ui.container.Panel
        READYLabel_2                  matlab.ui.control.Label
        SystemStatusLabel             matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        CSPILabel                     matlab.ui.control.Label
        ReynoldsNumberLabel           matlab.ui.control.Label
        VelocitymsLabel               matlab.ui.control.Label
        MassFlowkgsLabel              matlab.ui.control.Label
        HeatRemovedkWLabel            matlab.ui.control.Label
        PressureDropbarLabel          matlab.ui.control.Label
        PumpHeadmLabel                matlab.ui.control.Label
        PerformanceIndicatorsLabel    matlab.ui.control.Label
        READYLabel                    matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        Label_6                       matlab.ui.control.Label
        RUNSIMULATIONButton           matlab.ui.control.Button
        InputParametersPanel          matlab.ui.container.Panel
        DensitykgmEditField           matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        SpecificHeatJkgKEditField     matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        PumpPressurebarEditField      matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        ReturnTemperatureCEditField   matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        SupplyTemperatureCEditField   matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        CoolingWaterFlowmhrEditField  matlab.ui.control.NumericEditField
        CoolingWaterFlowmhrLabel      matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    methods (Access = private)
        
        function Results = RunLD3Simulation(app)
     
    %% Read Operator Inputs from GUI
    Q_total      = app.CoolingWaterFlowmhrEditField.Value;
    T_supply     = app.SupplyTemperatureCEditField.Value;
    T_return     = app.ReturnTemperatureCEditField.Value;
    PumpPressure = app.PumpPressurebarEditField.Value;
    Cp           = app.SpecificHeatJkgKEditField.Value;
    rho          = app.DensitykgmEditField.Value;

    %% Load Fixed Engineering Constants
    run(fullfile(fileparts(mfilename('fullpath')),'data.m'));

    %% Override with GUI Values
    Q_total      = app.CoolingWaterFlowmhrEditField.Value;
    T_supply     = app.SupplyTemperatureCEditField.Value;
    T_return     = app.ReturnTemperatureCEditField.Value;
    PumpPressure = app.PumpPressurebarEditField.Value;
    Cp           = app.SpecificHeatJkgKEditField.Value;
    rho          = app.DensitykgmEditField.Value;

    %% Call Engineering Simulation
    Results = RunSimulation( ...
        Q_total,...
        Q_hood,...
        Q_lance,...
        Q_ring,...
        Cp,...
        rho,...
        T_supply,...
        T_return,...
        PumpPressure,...
        g,...
        eta,...
        mu,...
        D,...
        L,...
        f,...
        HotIn,...
        HotOut,...
        U,...
        A);

        end
        
        function UpdateOutputs(app, Results)
            

    %% Pump Head
    app.pumphead.Text = sprintf('%.2f m', Results.PumpHead);

    %% Pressure Drop
    app.pressuredrop.Text = sprintf('%.3f bar', Results.PressureDropBar);

    %% Heat Removed
    app.heatremoved.Text = sprintf('%.2f kW', Results.HeatTransferKW);

    %% Mass Flow
    app.massflow.Text = sprintf('%.2f kg/s', Results.MassFlow);

    %% Velocity
    app.velocity.Text = sprintf('%.2f m/s', Results.Velocity);

    %% Reynolds Number
    app.reynoldsnumber.Text = sprintf('%.0f', Results.Reynolds);

    %% CSPI
    app.cspi.Text = sprintf('%.2f %%', Results.CSPI);

    %% System Status
   if isstring(Results.Status) || ischar(Results.Status)
    app.READYLabel_2.Text = char(Results.Status);
else
    app.READYLabel_2.Text = "READY";
end

        end

    function ValidateInputs(app)

    if app.CoolingWaterFlowmhrEditField.Value <= 0
        error('Cooling Water Flow must be greater than zero.');
    end

    if app.SupplyTemperatureCEditField.Value <= 0
        error('Supply Temperature must be greater than zero.');
    end

    if app.ReturnTemperatureCEditField.Value <= 0
        error('Return Temperature must be greater than zero.');
    end

    if app.PumpPressurebarEditField.Value <= 0
        error('Pump Pressure must be greater than zero.');
    end

    if app.SpecificHeatJkgKEditField.Value <= 0
        error('Specific Heat must be greater than zero.');
    end

    if app.DensitykgmEditField.Value <= 0
        error('Density must be greater than zero.');
    end

    if app.SupplyTemperatureCEditField.Value == app.ReturnTemperatureCEditField.Value
        error('Supply and Return Temperatures cannot be equal.');
    end

    end      


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNSIMULATIONButton
        function RUNSIMULATIONButtonPushed(app, event)


    try

        %% Change Status to RUNNING
        app.READYLabel_2.Text = 'RUNNING';
        app.StatusLamp.Color = [1 1 0];
        drawnow;

        %% Validate Inputs
        app.ValidateInputs();

        %% Run Engineering Simulation
        Results = app.RunLD3Simulation();

        %% Update GUI
        app.UpdateOutputs(Results);

        %% Simulation Completed
        app.READYLabel_2.Text = 'COMPLETED';
        app.StatusLamp.Color = [0 1 0];

    catch ME

        app.READYLabel_2.Text = 'ERROR';
        app.StatusLamp.Color = [1 0 0];

        uialert(app.UIFigure, ...
            ME.message, ...
            'Simulation Error');

    end      % <-- closes TRY

end          % <-- closes FUNCTION
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputParametersPanel = uipanel(app.GridLayout);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.CoolingWaterFlowmhrLabel = uilabel(app.InputParametersPanel);
            app.CoolingWaterFlowmhrLabel.Text = {'Cooling Water Flow '; '(m³/hr)'};
            app.CoolingWaterFlowmhrEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.CoolingWaterFlowmhrEditField.Value = 1566;
            app.Label = uilabel(app.InputParametersPanel);
            app.Label.Text = {'Supply Temperature '; '(°C)'};
            app.SupplyTemperatureCEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.SupplyTemperatureCEditField.Value = 82.18;
            app.Label_2 = uilabel(app.InputParametersPanel);
            app.Label_2.Text = {'Return Temperature '; '(°C)'};
            app.ReturnTemperatureCEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ReturnTemperatureCEditField.Value = 84.97;
            app.Label_3 = uilabel(app.InputParametersPanel);
            app.Label_3.Text = {'Pump Pressure '; '(bar)'};
            app.PumpPressurebarEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.PumpPressurebarEditField.Value = 20.35;
            app.Label_4 = uilabel(app.InputParametersPanel);
            app.Label_4.Text = {'Specific Heat '; '(J/kgK)'; ' '};
            app.SpecificHeatJkgKEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.SpecificHeatJkgKEditField.Value = 4180;
            app.Label_5 = uilabel(app.InputParametersPanel);
            app.Label_5.Text = 'Density (kg/m³)';
            app.DensitykgmEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.DensitykgmEditField.Value = 1000;
            app.RUNSIMULATIONButton = uibutton(app.GridLayout, 'push');
            app.RUNSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.RUNSIMULATIONButton.Text = 'RUN SIMULATION';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = 'StatusLamp';
            app.StatusLamp = uilamp(app.GridLayout);
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.READYLabel = uilabel(app.GridLayout);
            app.READYLabel.Text = 'READY';
            app.PerformanceIndicatorsLabel = uilabel(app.GridLayout);
            app.PerformanceIndicatorsLabel.Text = 'Performance Indicators';
            app.PumpHeadmLabel = uilabel(app.GridLayout);
            app.PumpHeadmLabel.Text = 'Pump Head (m)';
            app.PressureDropbarLabel = uilabel(app.GridLayout);
            app.PressureDropbarLabel.Text = 'Pressure Drop (bar)';
            app.HeatRemovedkWLabel = uilabel(app.GridLayout);
            app.HeatRemovedkWLabel.Text = {'Heat Removed '; '(kW)'};
            app.MassFlowkgsLabel = uilabel(app.GridLayout);
            app.MassFlowkgsLabel.Text = 'Mass Flow (kg/s)';
            app.VelocitymsLabel = uilabel(app.GridLayout);
            app.VelocitymsLabel.Text = 'Velocity (m/s)';
            app.ReynoldsNumberLabel = uilabel(app.GridLayout);
            app.ReynoldsNumberLabel.Text = 'Reynolds Number';
            app.CSPILabel = uilabel(app.GridLayout);
            app.CSPILabel.Text = 'CSPI';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '';
            app.SystemStatusLabel = uilabel(app.GridLayout);
            app.SystemStatusLabel.Text = 'System Status';
            app.READYLabel_2 = uilabel(app.GridLayout);
            app.READYLabel_2.Text = 'READY';
            app.EngineeringResultsPanel = uipanel(app.GridLayout);
            app.EngineeringResultsPanel.Title = 'Engineering Results';
            app.pumphead = uilabel(app.GridLayout);
            app.pumphead.Text = '-----';
            app.pressuredrop = uilabel(app.GridLayout);
            app.pressuredrop.Text = '------';
            app.heatremoved = uilabel(app.GridLayout);
            app.heatremoved.Text = '-----';
            app.massflow = uilabel(app.GridLayout);
            app.massflow.Text = '------';
            app.velocity = uilabel(app.GridLayout);
            app.velocity.Text = '-----';
            app.reynoldsnumber = uilabel(app.GridLayout);
            app.reynoldsnumber.Text = '------';
            app.cspi = uilabel(app.GridLayout);
            app.cspi.Text = '------';
            app.LD3COOLINGWATERSYSTEMDIGITALTWINBasicOxygenFurnaceLD3CoolingWaterSystemLabel = uilabel(app.UIFigure);
            app.LD3COOLINGWATERSYSTEMDIGITALTWINBasicOxygenFurnaceLD3CoolingWaterSystemLabel.Text = {'LD3 COOLING WATER SYSTEM DIGITAL TWIN'; 'Basic Oxygen Furnace (LD3) Cooling Water System'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LD3_DigitalTwin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end